classdef nCORTEx_console < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusBar                       slrealtime.ui.control.StatusBar
        SelectSubjectDropDown           matlab.ui.control.DropDown
        SelectSubjectDropDownLabel      matlab.ui.control.Label
        SessionTimerminEditField        matlab.ui.control.NumericEditField
        SessionTimerminEditFieldLabel   matlab.ui.control.Label
        PiezoPanel                      matlab.ui.container.Panel
        Piezo_FSEditField               matlab.ui.control.NumericEditField
        FSEditField_3Label              matlab.ui.control.Label
        Switch_4                        matlab.ui.control.Switch
        PiezoConfigDropDown             matlab.ui.control.DropDown
        PiezoConfigDropDownLabel        matlab.ui.control.Label
        VisionPanel                     matlab.ui.container.Panel
        WhiskFSEditField                matlab.ui.control.NumericEditField
        WhiskFSEditFieldLabel           matlab.ui.control.Label
        SGLXConfigDropDown_3            matlab.ui.control.DropDown
        SGLXConfigDropDown_3Label       matlab.ui.control.Label
        PupilFSEditField                matlab.ui.control.NumericEditField
        PupilFSEditField_2Label         matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        SGLXConfigDropDown_2            matlab.ui.control.DropDown
        SGLXConfigDropDown_2Label       matlab.ui.control.Label
        connectTarget                   matlab.ui.control.StateButton
        PhotometryPanel                 matlab.ui.container.Panel
        Photometry_FSEditField          matlab.ui.control.NumericEditField
        FSEditFieldLabel                matlab.ui.control.Label
        Switch_3                        matlab.ui.control.Switch
        DoricConfigDropDown             matlab.ui.control.DropDown
        DoricConfigDropDownLabel        matlab.ui.control.Label
        LoggingLabel                    matlab.ui.control.Label
        SelectProjectDirectoryDropDown  matlab.ui.control.DropDown
        SelectProjectDirectoryDropDownLabel  matlab.ui.control.Label
        NeuropixelsPanel                matlab.ui.container.Panel
        Npxls_FSEditField               matlab.ui.control.NumericEditField
        FSEditField_2Label              matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        SGLXConfigDropDown              matlab.ui.control.DropDown
        SGLXConfigDropDownLabel         matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        DataStreamingLabel              matlab.ui.control.Label
        PhaseDropDown                   matlab.ui.control.DropDown
        PhaseDropDownLabel              matlab.ui.control.Label
        SaveLogButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        slTarget=slrealtime; % Description
        isStopped % Description
        sampleTime=0.001 % Description   
        params=setBaseParams(struct);
    end
    
    methods (Access = private)
        
        function setSimulinkParams(app)
            T_npxls = (1/app.Npxls_FSEditField.Value)/(app.sampleTime);
            T_pupil = (1/app.PupilFSEditField.Value)/(app.sampleTime);
            T_whisk = (1/app.WhiskFSEditField.Value)/(app.sampleTime);
            load_system('nCORTEx');
            mdlWks = get_param('nCORTEx','ModelWorkspace');
            assignin(mdlWks, 'tStop', (app.SessionTimerminEditField.Value*60)); % minute to second conversion
            assignin(mdlWks, 'T_npxls', T_npxls);
            assignin(mdlWks, 'T_pupil', T_pupil);
            assignin(mdlWks, 'T_whisk', T_whisk);
            assignin(mdlWks, 'toneDuration', 1.5);
            assignin(mdlWks, 'maxFrames', (app.SessionTimerminEditField.Value*60)/app.sampleTime);
            % set_param('nCORTEx','ModelWorkspace',mdlWks);
            % set_param('nCORTEx','StopTime',app.SessionTimerminEditField.Value)
            % set_param('nCORTEx'...  
            %     , 'tStop', app.SessionTimerminEditField.Value
            %     , 'T_npxls', T_npxls ...
            %     , 'T_pupil', T_pupil ...
            %     , 'T_whisk', T_whisk ...
            %     , 'toneDuration', 1.5);
        end
        
        function pyDict = struct2dict(app, matStruct)
            % Returns a matlab struct as a text formatted python dictionary
            % containing struct fields as dict keys and struct values as dict values
            % Converts a MATLAB struct to a text-formatted Python dictionary
    
            % Start with an empty string
            pyDict = '{';
            
            % Loop through the fields of the struct
            fields = fieldnames(matStruct);
            for i = 1:length(fields)
                field = fields{i};
                value = matStruct.(field);
                
                % Convert the field name and value to strings
                fieldStr = sprintf('"%s"', field);
                valueStr = '';
                
                % Check the data type of the value and format it accordingly
                if ischar(value)
                    valueStr = sprintf('"%s"', value);
                elseif isnumeric(value) || islogical(value)
                    valueStr = num2str(value);
                elseif isstruct(value)
                    % Recursively convert nested structs
                    valueStr = struct2dict(app, value);
                else
                    % Handle other data types as needed
                    error('Unsupported data type in struct.');
                end
                
                % Combine the field and value strings
                pairStr = sprintf('%s: %s', fieldStr, valueStr);
                
                % Add the pair to the dictionary string
                if i < length(fields)
                    pyDict = [pyDict, pairStr, ', '];
                else
                    pyDict = [pyDict, pairStr];
                end
            end
            
            % Close the dictionary
            pyDict = [pyDict, '}'];
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.isStopped=0;            
            app.params.spinParams.execStatus='start';
            % re-update fps values based on GUI            
            app.params.spinParams.saveDir=app.SelectProjectDirectoryDropDownLabel.Text;
            

            %% Build and Load model
            tg = app.slTarget;
            disp(strcat('Accessing', {' '},tg.TargetSettings.name,{'...'}));
            % set Sim Pameters
            setSimulinkParams(app);
            slbuild('nCORTEx');                       
            load(tg,'nCORTEx');                                  

            app.slTarget=tg;

            % setenv('PYTHON', '/path/to/conda/envs/nCORTEx/bin/python')
            % setenv('PYTHON',fullfile(app.params.paths.stem,'.local','lib','python3.10'))
            % setenv('CONDA_PYTHON_EXE')
            %% Initiate processes 
            % system('#!/bin/bash')            
            % system('conda activate nCORTEx') % activate virtualenv
            % cmd = ['python spinTEx.py ',spinParams]; 
            % system(cmd) % start camera acquisition            
            
            % Use parfeval to run the Python scripts concurrently
            app.params.spinParams.camSelect=app.params.spinParams.pupilSN;
            spinParams0 = struct2dict(app, app.params.spinParams);
            app.params.spinParams.camSelect=app.params.spinParams.whiskSN;
            spinParams1 = struct2dict(app, app.params.spinParams);
            spinParamsJSON0 = jsonencode(spinParams0);
            spinParamsJSON1 = jsonencode(spinParams1);
            % system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON0 ' &' "./callSpinTEx.sh" '' spinParamsJSON1 '&']))            
            % poolSize = 1;  % Number of workers in the pool
            % backgroundPool = parpool(poolSize);
            % cmd = strjoin(["parallel './callSpinTEx.sh' ::: " spinParamsJSON0 ' ' spinParamsJSON1 '']);
            % parfeval(backgroundPool,@system,1,cmd);            
            system(strjoin(["parallel './callSpinTEx.sh' ::: " spinParamsJSON0 ' ' spinParamsJSON1 ' &']));
            pause(5)


            % system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON0 '']))

            start(app.slTarget)            
            
            % Draw a progress bar            
            sessionTime = app.SessionTimerminEditField.Value*60;
            cla(app.UIAxes)            
            while true
                % filledWidth=i;
                % log = getlogsout()
                widthPrct = (app.slTarget.ModelStatus.ExecTime/sessionTime);                                
                switch(app.params.hostName)
                    case 'electro'
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[0.31,0.84,0.29]);
                    case 'genoma'
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                    otherwise
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[0.83,0.62,0.16]);
                end
                drawnow;
                if app.isStopped
                    % If model completes without user's stop input, stop camera acquisition            
                    app.params.spinParams.execStatus='stop';
                    spinParams = struct2dict(app, app.params.spinParams); 
                    spinParamsJSON = jsonencode(spinParams);
                    disp("stopping slx")
                    stop(app.slTarget)
                    disp(strcat('Application',{' '},'Stopped'))
                    % Release resources
                    system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON '']))          
                    break;
                elseif string(app.slTarget.ModelStatus.State)=="DONE"
                    % If model completes without user's stop input, stop camera acquisition            
                    app.params.spinParams.execStatus='stop';
                    spinParams = struct2dict(app, app.params.spinParams); 
                    spinParamsJSON = jsonencode(spinParams);
                    disp("stopping slx")
                    stop(app.slTarget)
                    disp(strcat('Application',{' '},'Stopped'))
                    % Release resources
                    system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON ' &']))          
                    % system("pkill python")
                    break;
                end 
                
            end            
            
            % % If model completes without user's stop input, stop camera acquisition            
            % app.params.spinParams.execStatus='stop';
            % spinParams = struct2dict(app, app.params.spinParams); 
            % spinParamsJSON = jsonencode(spinParams);
            % disp("stopping slx")
            % stop(app.slTarget)
            % disp(strcat('Application',{' '},'Stopped'))

            % system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON '']))          
            

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isStopped=1;
            % tg = app.slTarget;                        
            % stop(app.slTarget);            
            % app.slTarget=tg;
            % disp(strcat('Application',{' '},'Stopped'))
        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonPushed(app, event)
            savePath = app.SelectProjectDirectoryDropDownLabel.Text;
            disp(['saving log data to: ',savePath]);
            logData = evalin('base','logsout');
            save(fullfile(app.SelectProjectDirectoryDropDownLabel.Text,"log.mat"),'logData')
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                    app.VisionPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.VisionPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            switch value
                case 'On'
                    app.NeuropixelsPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.NeuropixelsPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            switch value
                case 'On'
                    app.PhotometryPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.PhotometryPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            switch value
                case 'On'
                    app.PiezoPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.PiezoPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Value changed function: connectTarget
        function connectTargetValueChanged(app, event)
            value = app.connectTarget.Value;
            switch value
                case 1
                    tg = app.slTarget;
                    disp('connecting...')
                    tg.connect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'connected'))
                    app.connectTarget.Icon='slrtConnectIcon.png';
                    app.slTarget=tg;
                case 0
                    tg = app.slTarget;
                    disp('disconnecting...')
                    tg.disconnect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'disconnected'))
                    app.connectTarget.Icon='slrtDisconnectIcon.png';
                    app.slTarget=tg;
            end
        end

        % Value changed function: SelectProjectDirectoryDropDown
        function SelectProjectDirectoryDropDownValueChanged(app, event)
            value = app.SelectProjectDirectoryDropDown.Value;            
            switch value
                case 'Neuromodulation for Pain'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Neuromodulation-for-Pain','Raw Behavior Data');
                case 'Neurotransmitter Exploration'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');
                case 'Thalamic-Pupil Synch'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Thalamic-Pupil-Synchronization','Raw Behavior Data');
                case 'SSD'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');;
                    app.SelectProjectDirectoryDropDownLabel.Text = 'SSD missing mountpoint';
                    disp('warning: SSD missing mountpoint')
                case 'Browse'
                    path = uigetdir;
                    app.SelectProjectDirectoryDropDownLabel.Text = path;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.SaveLogButton = uibutton(app.UIFigure, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLogButton.Text = 'Save Log';
            app.PhaseDropDownLabel = uilabel(app.UIFigure);
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.UIFigure);
            app.DataStreamingLabel = uilabel(app.UIFigure);
            app.DataStreamingLabel.Text = 'Data Streaming';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'slrtRunIcon.png';
            app.StartButton.Text = '';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'slrtStopIcon.png';
            app.StopButton.Text = '';
            app.NeuropixelsPanel = uipanel(app.UIFigure);
            app.NeuropixelsPanel.Title = 'Neuropixels';
            app.SGLXConfigDropDownLabel = uilabel(app.NeuropixelsPanel);
            app.SGLXConfigDropDownLabel.Text = 'SGLX Config';
            app.SGLXConfigDropDown = uidropdown(app.NeuropixelsPanel);
            app.SGLXConfigDropDown.Value = 'Option 3';
            app.Switch_2 = uiswitch(app.NeuropixelsPanel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'On';
            app.FSEditField_2Label = uilabel(app.NeuropixelsPanel);
            app.FSEditField_2Label.Text = 'FS';
            app.Npxls_FSEditField = uieditfield(app.NeuropixelsPanel, 'numeric');
            app.Npxls_FSEditField.Value = 250;
            app.SelectProjectDirectoryDropDownLabel = uilabel(app.UIFigure);
            app.SelectProjectDirectoryDropDownLabel.Text = 'Select Project Directory';
            app.SelectProjectDirectoryDropDown = uidropdown(app.UIFigure);
            app.SelectProjectDirectoryDropDown.Items = {'Neuromodulation for Pain', 'Neurotransmitter Exploration', 'Thalamic-Pupil Synch', 'SSD', 'Browse'};
            app.SelectProjectDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProjectDirectoryDropDownValueChanged, true);
            app.SelectProjectDirectoryDropDown.Value = 'Neuromodulation for Pain';
            app.LoggingLabel = uilabel(app.UIFigure);
            app.LoggingLabel.Text = 'Logging';
            app.PhotometryPanel = uipanel(app.UIFigure);
            app.PhotometryPanel.Title = 'Photometry';
            app.DoricConfigDropDownLabel = uilabel(app.PhotometryPanel);
            app.DoricConfigDropDownLabel.Text = 'Doric Config';
            app.DoricConfigDropDown = uidropdown(app.PhotometryPanel);
            app.DoricConfigDropDown.Value = 'Option 3';
            app.Switch_3 = uiswitch(app.PhotometryPanel, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'On';
            app.FSEditFieldLabel = uilabel(app.PhotometryPanel);
            app.FSEditFieldLabel.Text = 'FS';
            app.Photometry_FSEditField = uieditfield(app.PhotometryPanel, 'numeric');
            app.connectTarget = uibutton(app.UIFigure, 'state');
            app.connectTarget.ValueChangedFcn = createCallbackFcn(app, @connectTargetValueChanged, true);
            app.connectTarget.Icon = 'slrtDisconnectIcon.png';
            app.connectTarget.Text = 'Button';
            app.VisionPanel = uipanel(app.UIFigure);
            app.VisionPanel.Title = 'Vision';
            app.SGLXConfigDropDown_2Label = uilabel(app.VisionPanel);
            app.SGLXConfigDropDown_2Label.Text = 'SGLX Config';
            app.SGLXConfigDropDown_2 = uidropdown(app.VisionPanel);
            app.SGLXConfigDropDown_2.Value = 'Option 3';
            app.Switch = uiswitch(app.VisionPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.PupilFSEditField_2Label = uilabel(app.VisionPanel);
            app.PupilFSEditField_2Label.Text = 'Pupil FS';
            app.PupilFSEditField = uieditfield(app.VisionPanel, 'numeric');
            app.PupilFSEditField.Value = 10;
            app.SGLXConfigDropDown_3Label = uilabel(app.VisionPanel);
            app.SGLXConfigDropDown_3Label.Text = 'SGLX Config';
            app.SGLXConfigDropDown_3 = uidropdown(app.VisionPanel);
            app.SGLXConfigDropDown_3.Value = 'Option 3';
            app.WhiskFSEditFieldLabel = uilabel(app.VisionPanel);
            app.WhiskFSEditFieldLabel.Text = 'Whisk FS';
            app.WhiskFSEditField = uieditfield(app.VisionPanel, 'numeric');
            app.WhiskFSEditField.Value = 50;
            app.PiezoPanel = uipanel(app.UIFigure);
            app.PiezoPanel.Title = 'Piezo';
            app.PiezoConfigDropDownLabel = uilabel(app.PiezoPanel);
            app.PiezoConfigDropDownLabel.Text = 'Piezo Config';
            app.PiezoConfigDropDown = uidropdown(app.PiezoPanel);
            app.PiezoConfigDropDown.Value = 'Option 3';
            app.Switch_4 = uiswitch(app.PiezoPanel, 'slider');
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = 'On';
            app.FSEditField_3Label = uilabel(app.PiezoPanel);
            app.FSEditField_3Label.Text = 'FS';
            app.Piezo_FSEditField = uieditfield(app.PiezoPanel, 'numeric');
            app.SessionTimerminEditFieldLabel = uilabel(app.UIFigure);
            app.SessionTimerminEditFieldLabel.Text = 'Session Timer (min )';
            app.SessionTimerminEditField = uieditfield(app.UIFigure, 'numeric');
            app.SessionTimerminEditField.Value = 30;
            app.SelectSubjectDropDownLabel = uilabel(app.UIFigure);
            app.SelectSubjectDropDownLabel.Text = 'Select Subject';
            app.SelectSubjectDropDown = uidropdown(app.UIFigure);
            app.SelectSubjectDropDown.Items = {'Neuromodulation for Pain', 'Neurotransmitter Exploration', 'Thalamic-Pupil Synch', 'SSD', 'Browse'};
            app.SelectSubjectDropDown.Value = 'Browse';
            app.StatusBar = slrealtime.ui.control.StatusBar(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nCORTEx_console

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end